classdef BajaCVTSim_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PrimaryPlotPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Plot3                           matlab.ui.control.UIAxes
        Plot2                           matlab.ui.control.UIAxes
        Plot1                           matlab.ui.control.UIAxes
        ButtonPanel                     matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ShowTuningGuideButton           matlab.ui.control.Button
        ToggleRecordedButton            matlab.ui.control.StateButton
        PlotShiftRatioDataButton        matlab.ui.control.Button
        PlotClampingForceButton         matlab.ui.control.Button
        PlotEngineSpeedDataButton       matlab.ui.control.Button
        TablePanel                      matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        ConstantValuesReadfromExcelLabel  matlab.ui.control.Label
        SimulationControlsPanel         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        UnabletoSolveLamp               matlab.ui.control.Lamp
        UnabletoSolveLampLabel          matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        UIVariablePanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PrimarySpringDropDown           matlab.ui.control.DropDown
        PrimarySpringDropDownLabel      matlab.ui.control.Label
        HelixAngledegEditField          matlab.ui.control.NumericEditField
        HelixAngledegEditFieldLabel     matlab.ui.control.Label
        SecondarySpringDropDown         matlab.ui.control.DropDown
        SecondarySpringDropDown_2Label  matlab.ui.control.Label
        PretensionSettingDropDown       matlab.ui.control.DropDown
        PretensionSettingDropDownLabel  matlab.ui.control.Label
        RampAngledegEditField           matlab.ui.control.NumericEditField
        RampAngledegEditFieldLabel      matlab.ui.control.Label
        FlyweightMassgEditField         matlab.ui.control.NumericEditField
        FlyweightMassgEditFieldLabel    matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        %Store UI State Variables
        SelectedValue1; SelectedValue2; SelectedValue3
        SelectedValue4; SelectedValue5; SelectedValue6
        SelectedValue7; SelectedValue8

        %Store Excel State Variables
        V1;  V2;  V3;  V4;  V5;  V6;  V7;  V8;  V9;  V10; V11; V12; V13; V14
        V15; V16; V17; V18; V19; V20; V21; V22; V23; V24; V25; V26
        V27; V28; V29; V30; V31; V32; V33; V34; V35; V36; V37; V38
        
        %Store Results State Variables
        R1; R2; R3; R4; R5; R6; R7; R8; R9; R10; R11; R12;

        %Store Engine State Arrays
        engineRPM
        torque    

        %Store Recorded Value Reference Plots
        Recorded1
        Recorded2
        Recorded3
        Recorded4
        Recorded5
        Recorded6

        %Store Model Workspace
        MWks
    end

    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Copy Excel File to Temp
            copyfile('BajaCVTSim_ExcelData.xlsx','temp.xlsx');
            
            %Read Input Column From Excel File
            T1 = readtable('temp.xlsx','Range','C3:C33');
            Var1 = readtable('temp.xlsx','Range','B3:C33');

            %Read Engine Data Columns From Excel File
            T2 = readtable('temp.xlsx','Range','G23:I34');

            %Exract Excel Data and Assign to State Variables
            app.V1 = T1{1,:};   app.V2 = T1{2,:};   app.V3 = T1{3,:};
            app.V4 = T1{4,:};   app.V5 = T1{5,:};   app.V6 = T1{6,:};
            app.V7 = T1{7,:};   app.V8 = T1{8,:};   app.V9 = T1{9,:};
            app.V10 = T1{10,:}; app.V11 = T1{11,:}; app.V12 = T1{12,:};
            app.V13 = T1{13,:}; app.V14 = T1{14,:}; app.V15 = T1{15,:};
            app.V16 = T1{16,:}; app.V17 = T1{17,:}; app.V18 = T1{18,:};
            app.V19 = T1{19,:}; app.V20 = T1{20,:}; app.V21 = T1{21,:};
            app.V22 = T1{22,:}; app.V23 = T1{23,:}; app.V24 = T1{24,:};
            app.V25 = T1{25,:}; app.V26 = T1{26,:}; app.V27 = T1{27,:};
            app.V28 = T1{28,:}; app.V29 = T1{29,:}; app.V30 = T1{30,:};
            app.V31 = T1{31,:}; 
            
            %Exract Excel Arrays & Assign to State Arrays
            app.engineRPM = T2{:,1}'; 
            app.torque = T2{:,2}';
            app.UITable.Data = Var1;

            %Load Simulink Model & Create Designatd Workspace
            model = "BajaCVTSim_SimulinkModel";
            load_system(model);
            mdlWks = get_param(model,"ModelWorkspace");
            app.MWks = mdlWks;

            %Assign Excel Data In Designated Workspace
            assignin(mdlWks,'Cd',app.V1);        assignin(mdlWks,'U',app.V2);
            assignin(mdlWks,'theta',app.V3);     assignin(mdlWks,'I_low',app.V4);
            assignin(mdlWks,'I_high',app.V5);    assignin(mdlWks,'Rp_int',app.V6);
            assignin(mdlWks,'Rp_end',app.V7);    assignin(mdlWks,'Rs_int',app.V9);
            assignin(mdlWks,'Rs_end',app.V8);    assignin(mdlWks,'X_max',app.V10);
            assignin(mdlWks,'X_0',app.V11);      assignin(mdlWks,'n_arm',app.V12);
            assignin(mdlWks,'R_link',app.V13);   assignin(mdlWks,'L_link',app.V14);
            assignin(mdlWks,'m_link',app.V15);   assignin(mdlWks,'Rfly_max',app.V16);
            assignin(mdlWks,'Rfly_min',app.V17); assignin(mdlWks,'Y_max',app.V18);
            assignin(mdlWks,'Y_0',app.V19);      assignin(mdlWks,'phi_max',app.V20);
            assignin(mdlWks,'R_helix',app.V21);  assignin(mdlWks,'m_ve',app.V22);
            assignin(mdlWks,'axle_F',app.V23);   assignin(mdlWks,'axle_R',app.V24);
            assignin(mdlWks,'CG',app.V25);       assignin(mdlWks,'tr',app.V26);
            assignin(mdlWks,'R_gear',app.V27);   assignin(mdlWks,'SS_set',app.V28);
            assignin(mdlWks,'LR_set',app.V29);   assignin(mdlWks,'Id_set',app.V30);
            assignin(mdlWks,'RD_set',app.V31);

            assignin(mdlWks,'eng_rpm',app.engineRPM);
            assignin(mdlWks,'eng_trq',app.torque);

        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
           %% UI Controls

            %Clear Exisiting Plots
            cla(app.Plot1)
            cla(app.Plot2)
            cla(app.Plot3)
            
            %Update Button/Label UI For Status
            app.RunAnalysisButton.BackgroundColor = [1 1 0];
            app.UnabletoSolveLamp.Visible = 'off';
            app.UnabletoSolveLampLabel.Visible = 'off';

            %Assign UI Variable Inputs
            if app.SecondarySpringDropDown.Value == "Yellow"
                app.V32 = 14.65;
                app.V33 = 0.4644;
            else
                app.V32 = 21.25;
                app.V33 = 0.592;
            end

            if app.PrimarySpringDropDown.Value == "Black"
                app.V37 = 43.75;
            elseif app.PrimarySpringDropDown.Value == "Orange"
                app.V37 = 79.89;
            else
                app.V37 = 84.79;
            end

            if app.PretensionSettingDropDown.Value == "Set 1"
                app.V38 = 0;
            elseif app.PretensionSettingDropDown.Value == "Set 2"
                app.V38 = 25;
            elseif app.PretensionSettingDropDown.Value == "Set 3"
                app.V38 = 50;
            elseif app.PretensionSettingDropDown.Value == "Set 4"
                app.V38 = 75;
            elseif app.PretensionSettingDropDown.Value == "Set 5"
                app.V38 = 100;
            elseif app.PretensionSettingDropDown.Value == "Set 6"
                app.V38 = 125;
            elseif app.PretensionSettingDropDown.Value == "Set 7"
                app.V38 = 150;
            elseif app.PretensionSettingDropDown.Value == "Set 8"
                app.V38 = 175;
            else
                app.V38 = 200;
            end

            app.V34 = app.FlyweightMassgEditField.Value;
            app.V35 = app.RampAngledegEditField.Value;
            app.V36 = app.HelixAngledegEditField.Value;

            % Define Invalid Setups   
            if     app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Black" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 2"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 3"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 4"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 2"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 3"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 4"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Red" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 5"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Black" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Black" && ...
                   app.PretensionSettingDropDown.Value == "Set 2"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Black" && ...
                   app.PretensionSettingDropDown.Value == "Set 3"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 2"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 3"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 4"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 5"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 6"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Orange" && ...
                   app.PretensionSettingDropDown.Value == "Set 7"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 1"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 2"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 3"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 4"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 5"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 6"
                   error = 1;
            elseif app.SecondarySpringDropDown.Value == "Yellow" && ...
                   app.PrimarySpringDropDown.Value == "Purple" && ...
                   app.PretensionSettingDropDown.Value == "Set 7"
                   error = 1;
            else
                error = 0;
            end

            % Invalid Setup Check
            if error == 1
                app.RunAnalysisButton.BackgroundColor = [1 0 0];
                app.UnabletoSolveLamp.Visible = 'on';
                app.UnabletoSolveLampLabel.Visible = 'on';
            else
                %% Run Simulink Model     
    
                %Assign UI Data In Designated Workspace
                assignin(app.MWks,"kp_lr",app.V37);    assignin(app.MWks,'ks_lr',app.V32);
                assignin(app.MWks,'ks_tr',app.V33);    assignin(app.MWks,"phi_0",app.V38);
                assignin(app.MWks,'m_fly',app.V34);    assignin(app.MWks,'Ramp',app.V35);
                assignin(app.MWks,'Helix',app.V36);
    
                %Run Simulink Model
                sim("BajaCVTSim_SimulinkModel.slx");
    
                %Extract Logged Signals
                sig1 = logsout.getElement('sig_EngineSpeed');
                sig2 = logsout.getElement('sig_SR');
                sig3 = logsout.getElement('sig_Velocity');
                sig4 = logsout.getElement('sig_Distance');
                sig5 = logsout.getElement('sig_Slip');
                sig6 = logsout.getElement('sig_PrimaryClamp');
                sig7 = logsout.getElement('sig_SecondaryClamp');
                sig8 = logsout.getElement('sig_PrimaryTrq');
                sig9 = logsout.getElement('sig_SecondaryTrq');
                sig10 = logsout.getElement('sig_PrimaryPitch');
                sig11 = logsout.getElement('sig_SecondaryPitch');
                
                %Assign Signal Data To Variable Name
                Engine_Speed = sig1.Values.Data;
                SR = sig2.Values.Data;
                Velocity = sig3.Values.Data;
                Distance = sig4.Values.Data;
                Slip = sig5.Values.Data;
                Clamp_pri = sig6.Values.Data;
                Clamp_sec = sig7.Values.Data;
                Trq_pri = sig8.Values.Data;
                Trq_sec = sig9.Values.Data;
                Rp_pitch = sig10.Values.Data;
                Rs_pitch = sig11.Values.Data;
    
                %Assign Variables To Stored State Data
                app.R1 = Engine_Speed;
                app.R2 = SR;
                app.R3 = Velocity;
                app.R4 = Distance;
                app.R5 = Slip;
                app.R6 = Clamp_pri;
                app.R7 = Clamp_sec;
                app.R8 = Trq_pri;
                app.R9 = Trq_sec;
                app.R10 = Rp_pitch;
                app.R11 = Rs_pitch;
                app.R12 = tout;
    
                %Plot Engine Speed to Velocity
                ys = Engine_Speed;
                mask = SR < app.V4;
                ys(mask) = smooth(Engine_Speed(mask),0.05);
    
                plot(app.Plot1,Velocity,ys,'LineWidth',2);
                ylim(app.Plot1,[2000 3800]);
                y1=yline(app.Plot1,app.V28,'--','Ideal Engine Speed','color',[0 0 0],'fontsize',14,'FontWeight','bold','LineWidth',1.5);
                y1.LabelVerticalAlignment='bottom';
                y1=yline(app.Plot1,app.V29,'--','Ideal Engagement Speed','color',[0 0 0],'fontsize',14,'FontWeight','bold','LineWidth',1.5);
                y1.LabelVerticalAlignment='bottom';
    
                %Plot Distance to Time
                D_target1 = 100;
                [~,idx1] = min(abs(Distance - D_target1));
                D_near1 = Distance(idx1);
                T_near1 = tout(idx1)-5;
                D_target2 = 150;
                [~,idx2] = min(abs(Distance - D_target2));
                D_near2 = Distance(idx2);
                T_near2 = tout(idx2)-5;
                
                plot(app.Plot2,tout-5,Distance,'LineWidth',2);
                plot(app.Plot2,T_near1,D_near1,'ro','MarkerSize',10,'LineWidth',2);
                plot(app.Plot2,T_near2,D_near2,'ro','MarkerSize',10,'LineWidth',2);
                ylim(app.Plot2,[0 200]);
                xlim(app.Plot2,[0 10]);
                text(app.Plot2,T_near1,D_near1, sprintf('  <--Simulated (100ft) = %.2f',T_near1), ...
                    'VerticalAlignment', 'middle', 'HorizontalAlignment', 'left','FontSize',18,'color',[0 0 0],'FontWeight','bold');
                text(app.Plot2,T_near2,D_near2, sprintf('  <--Simulated (150ft) = %.2f',T_near2), ...
                    'VerticalAlignment', 'middle', 'HorizontalAlignment', 'left','FontSize',18,'color',[0 0 0],'FontWeight','bold');
                
                %Plot Distance Traveled to Time
                D_target = 500;
                [~,idx] = min(abs(Distance - D_target));
                D_near = Distance(idx);
                V_near = Velocity(idx);
                [maxVal2, idx2] = max(Velocity);
               
                plot(app.Plot3,Distance,Velocity,'LineWidth',2);
                plot(app.Plot3,D_near,V_near,'ro','MarkerSize',10,'LineWidth',2)
                plot(app.Plot3,Distance(idx2),maxVal2,'ro','MarkerSize',10,'LineWidth',2);
                ylim(app.Plot3,[0 50]);
                text(app.Plot3,D_near,V_near, sprintf('  <--Simulated (500ft) = %.2f',V_near), ...
                    'VerticalAlignment', 'top', 'HorizontalAlignment', 'left','FontSize',18,'color',[0 0 0],'FontWeight','bold');
                text(app.Plot3,Distance(idx2), maxVal2, sprintf('Simulated Top Speed = %.2f-->  ', maxVal2), ...
                    'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize',18,'color',[0 0 0],'FontWeight','bold');
                
                %Plot Recorded Values for Reference
                Recorded_value1 = 4.03;
                Recorded_value2 = 5.33;
                D1 = 100;
                D2 = 150;
                refx1 = [0  Recorded_value1];
                refy1 = [0 D1];
                refx2 = [0  Recorded_value2];
                refy2 = [0 D2];
    
                app.Recorded1= plot(app.Plot2,refx1,refy1,'o--','color',[1 0 0]);
                app.Recorded2 = plot(app.Plot2,refx2,refy2,'o--','color',[1 0 0]);
                app.Recorded3 = text(app.Plot2,Recorded_value1,D1, sprintf('Recorded (100ft) = %.2f         ', ...
                    Recorded_value1),'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
                app.Recorded4 = text(app.Plot2,Recorded_value2,D2, sprintf(' Recorded (150ft) = %.2f        ', ...
                    Recorded_value2),'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
    
                %Plot Recorded Values For Reference
                Recorded_value3 = 33.85;
                Dist = 500;
                refy = [0  Recorded_value3];
                refx = [0 Dist];
    
                app.Recorded5 = plot(app.Plot3,refx,refy,'o--','color',[1 0 0]);
                app.Recorded6 = text(app.Plot3,Dist,Recorded_value3, sprintf('Recorded (500ft) = %.2f       ',Recorded_value3), ...
                    'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
    
                %Hide Plots
                app.Recorded1.Visible = 'off';
                app.Recorded2.Visible = 'off';
                app.Recorded3.Visible = 'off';
                app.Recorded4.Visible = 'off';
                app.Recorded5.Visible = 'off';
                app.Recorded6.Visible = 'off';
    
                %Update UI Buttons/Labels
                app.RunAnalysisButton.BackgroundColor = [0 1 0];
                
                app.PlotClampingForceButton.Enable = "on";
                app.PlotShiftRatioDataButton.Enable = "on";
                app.PlotEngineSpeedDataButton.Enable = "on";
                app.ToggleRecordedButton.Enable = "on";
                app.ShowTuningGuideButton.Enable = "on";


                %Calculate Error
                E1 = abs((T_near1 - Recorded_value1)/Recorded_value1)*100;
                E2 = abs((T_near2 - Recorded_value2)/Recorded_value2)*100;
                E3 = abs((V_near - Recorded_value3)/Recorded_value3)*100;
            end
        end

        % Value changed function: PrimarySpringDropDown
        function PrimarySpringDropDownValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue1 = app.PrimarySpringDropDown.Value;

        end

        % Value changed function: PretensionSettingDropDown
        function PretensionSettingDropDownValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue3 = app.PretensionSettingDropDown.Value;

        end

        % Value changed function: FlyweightMassgEditField
        function FlyweightMassgEditFieldValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue4 = app.FlyweightMassgEditField.Value;

        end

        % Value changed function: RampAngledegEditField
        function RampAngledegEditFieldValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue5 = app.RampAngledegEditField.Value;

        end

        % Value changed function: SecondarySpringDropDown
        function SecondarySpringDropDownValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue2 = app.SecondarySpringDropDown.Value;

        end

        % Value changed function: HelixAngledegEditField
        function HelixAngledegEditFieldValueChanged(app, event)
            
            %Update State Variable with Changed Value
            app.SelectedValue6 = app.HelixAngledegEditField.Value;

        end

        % Button pushed function: PlotClampingForceButton
        function PlotClampingForceButtonPushed(app, event)
            figure('Name','Clamping Forces','NumberTitle','off')

            %Plot Primary Clamping Force to Shift Ratio
            subplot(2,1,1)
            ys = app.R6;
            mask = app.R2 < app.V4;
            ys(mask) = smooth(app.R6(mask),0.1);

            plot(app.R2,ys,'Linewidth',1.5)
            xlim([app.V5 (app.V4)-0.01]);
            title('Primary Clamping Force Through Shift');
            ylabel('Clamping Force (lbf)');
            xlabel('SR');
            set(gca,'XDir','reverse');
            grid on
            
            %Plot Secondary Clamping Force to Shift Ratio
            subplot(2,1,2)
            ys = app.R7;
            mask = app.R2 < app.V4;
            ys(mask) = smooth(app.R7(mask),0.1);
            
            plot(app.R2,ys,'Linewidth',1.5)
            xlim([app.V5 (app.V4)-0.01]);
            title('Secondary Clamping Force Through Shift');
            ylabel('Clamping Force (lbf)');
            xlabel('Shift Ratio');
            set(gca,'XDir','reverse');
            grid on

        end

        % Button pushed function: PlotEngineSpeedDataButton
        function PlotEngineSpeedDataButtonPushed(app, event)
            figure ('Name','Engine Speed Data','NumberTitle','off')

            %Plot Engine Speed to Through Shift
            ys = app.R1;
            mask = app.R2 < app.V4;
            ys(mask) = smooth(app.R1(mask),0.05);

            subplot(2,1,1)
            plot(app.R2,ys,'LineWidth',1.5)
            xlim([(app.V5)-0.1 (app.V4)+0.1]);
            ylim([2000 3800]);
            set(gca,'XDir','reverse');
            y1=yline(app.V28,'--','Ideal Engine Speed','color',[1 0.5 0],'fontsize',8);
            y1.LabelVerticalAlignment='middle';
            title('Engine Speed Through Shift');
            ylabel('Engine Speed (rpm)');
            xlabel('Shift Ratio');
            grid on
            
            %Plot Engine Speed To Slip
            subplot(2,1,2)
            plot(app.R1,app.R5,'LineWidth',1.5)
            ylim([-20 120]);
            x1=xline(app.V29,'--','Ideal Engagement Speed','color',[0 1 0],'fontsize',8);
            x1.LabelVerticalAlignment='middle';
            title('Belt Engagement');
            ylabel('Slip Percentage (%)');
            xlabel('Engine Speed (rpm)');
            grid on

        end

        % Button pushed function: PlotShiftRatioDataButton
        function PlotShiftRatioDataButtonPushed(app, event)
            figure ('Name','Shifting Data','NumberTitle','off')

            %Plot Total Pitch Radius to Shift Ratio
            subplot(2,1,1)
            plot(app.R2,app.R10,'LineWidth',1.5); hold on
            plot(app.R2,app.R11,'y','LineWidth',1.5)
            xlim([app.V5 (app.V4)-0.1])
            set(gca,'XDir','reverse');
            title('Pitch Radius Through Shift');
            ylabel('Pitch Radius (in)')
            xlabel('Shift Ratio')
            legend('Primary Pitch','Secondary Pitch')
            grid on
            
            %Plot Shift Ratio
            subplot(2,1,2)
            plot(app.R12,app.R2,'linewidth',1.5)
            ylim([0.5 4.5]);
            title('Shift Ratio');
            ylabel('Shift Ratio');
            xlabel('Time (s)');
            grid on
        end

        % Value changed function: ToggleRecordedButton
        function ToggleRecordedButtonValueChanged(app, event)
            if app.ToggleRecordedButton.Value
                app.Recorded1.Visible = 'on';
                app.Recorded2.Visible = 'on';
                app.Recorded3.Visible = 'on';
                app.Recorded4.Visible = 'on';
                app.Recorded5.Visible = 'on';
                app.Recorded6.Visible = 'on';
                app.ToggleRecordedButton.BackgroundColor = [0 1 0];
                
            else
                app.Recorded1.Visible = 'off';
                app.Recorded2.Visible = 'off';
                app.Recorded3.Visible = 'off';
                app.Recorded4.Visible = 'off';
                app.Recorded5.Visible = 'off';
                app.Recorded6.Visible = 'off';
                app.ToggleRecordedButton.BackgroundColor = [1 1 1];
                
            end   
        end

        % Button pushed function: ShowTuningGuideButton
        function ShowTuningGuideButtonPushed(app, event)
            
            figure ('Name','Tuning Guide','NumberTitle','off')
            image = imread('TuningGuide.jpg');
            imshow(image);

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            
            figure ('Name','Help','NumberTitle','off')
            image = imread('Help.jpg');
            imshow(image);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIVariablePanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.UIVariablePanel);
            app.FlyweightMassgEditFieldLabel = uilabel(app.GridLayout2);
            app.FlyweightMassgEditFieldLabel.Text = 'Flyweight Mass (g)';
            app.FlyweightMassgEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FlyweightMassgEditField.Limits = [60 200];
            app.FlyweightMassgEditField.RoundFractionalValues = 'on';
            app.FlyweightMassgEditField.ValueChangedFcn = createCallbackFcn(app, @FlyweightMassgEditFieldValueChanged, true);
            app.FlyweightMassgEditField.Value = 120;
            app.RampAngledegEditFieldLabel = uilabel(app.GridLayout2);
            app.RampAngledegEditFieldLabel.Text = 'Ramp Angle (deg)';
            app.RampAngledegEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RampAngledegEditField.Limits = [10 25];
            app.RampAngledegEditField.RoundFractionalValues = 'on';
            app.RampAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @RampAngledegEditFieldValueChanged, true);
            app.RampAngledegEditField.Value = 18;
            app.PretensionSettingDropDownLabel = uilabel(app.GridLayout2);
            app.PretensionSettingDropDownLabel.Text = 'Pretension Setting';
            app.PretensionSettingDropDown = uidropdown(app.GridLayout2);
            app.PretensionSettingDropDown.Items = {'Set 1', 'Set 2', 'Set 3', 'Set 4', 'Set 5', 'Set 6', 'Set 7', 'Set 8', 'Set 9'};
            app.PretensionSettingDropDown.ValueChangedFcn = createCallbackFcn(app, @PretensionSettingDropDownValueChanged, true);
            app.PretensionSettingDropDown.Value = 'Set 6';
            app.SecondarySpringDropDown_2Label = uilabel(app.GridLayout2);
            app.SecondarySpringDropDown_2Label.Text = 'Secondary Spring';
            app.SecondarySpringDropDown = uidropdown(app.GridLayout2);
            app.SecondarySpringDropDown.Items = {'Yellow', 'Red'};
            app.SecondarySpringDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondarySpringDropDownValueChanged, true);
            app.SecondarySpringDropDown.Value = 'Red';
            app.HelixAngledegEditFieldLabel = uilabel(app.GridLayout2);
            app.HelixAngledegEditFieldLabel.Text = 'Helix Angle (deg)';
            app.HelixAngledegEditField = uieditfield(app.GridLayout2, 'numeric');
            app.HelixAngledegEditField.Limits = [20 40];
            app.HelixAngledegEditField.RoundFractionalValues = 'on';
            app.HelixAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @HelixAngledegEditFieldValueChanged, true);
            app.HelixAngledegEditField.Value = 30;
            app.PrimarySpringDropDownLabel = uilabel(app.GridLayout2);
            app.PrimarySpringDropDownLabel.Text = 'Primary Spring';
            app.PrimarySpringDropDown = uidropdown(app.GridLayout2);
            app.PrimarySpringDropDown.Items = {'Black', 'Orange', 'Purple'};
            app.PrimarySpringDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimarySpringDropDownValueChanged, true);
            app.PrimarySpringDropDown.Value = 'Black';
            app.SimulationControlsPanel = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.SimulationControlsPanel);
            app.RunAnalysisButton = uibutton(app.GridLayout4, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.HelpButton = uibutton(app.GridLayout4, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.UnabletoSolveLampLabel = uilabel(app.GridLayout4);
            app.UnabletoSolveLampLabel.Visible = 'off';
            app.UnabletoSolveLampLabel.Text = 'Unable to Solve';
            app.UnabletoSolveLamp = uilamp(app.GridLayout4);
            app.UnabletoSolveLamp.Visible = 'off';
            app.TablePanel = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.TablePanel);
            app.ConstantValuesReadfromExcelLabel = uilabel(app.GridLayout5);
            app.ConstantValuesReadfromExcelLabel.Text = 'Constant Values (Read from Excel)';
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ButtonPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.ButtonPanel);
            app.PlotEngineSpeedDataButton = uibutton(app.GridLayout, 'push');
            app.PlotEngineSpeedDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEngineSpeedDataButtonPushed, true);
            app.PlotEngineSpeedDataButton.Enable = 'off';
            app.PlotEngineSpeedDataButton.Text = 'Plot Engine Speed Data';
            app.PlotClampingForceButton = uibutton(app.GridLayout, 'push');
            app.PlotClampingForceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotClampingForceButtonPushed, true);
            app.PlotClampingForceButton.Enable = 'off';
            app.PlotClampingForceButton.Text = 'Plot Clamping Force ';
            app.PlotShiftRatioDataButton = uibutton(app.GridLayout, 'push');
            app.PlotShiftRatioDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotShiftRatioDataButtonPushed, true);
            app.PlotShiftRatioDataButton.Enable = 'off';
            app.PlotShiftRatioDataButton.Text = 'Plot Shift Ratio Data';
            app.ToggleRecordedButton = uibutton(app.GridLayout, 'state');
            app.ToggleRecordedButton.ValueChangedFcn = createCallbackFcn(app, @ToggleRecordedButtonValueChanged, true);
            app.ToggleRecordedButton.Enable = 'off';
            app.ToggleRecordedButton.Text = 'Toggle Recorded';
            app.ShowTuningGuideButton = uibutton(app.GridLayout, 'push');
            app.ShowTuningGuideButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTuningGuideButtonPushed, true);
            app.ShowTuningGuideButton.Enable = 'off';
            app.ShowTuningGuideButton.Text = 'Show Tuning Guide';
            app.PrimaryPlotPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.PrimaryPlotPanel);
            app.Plot1 = uiaxes(app.GridLayout3);
            title(app.Plot1, 'Engine Speed To Velocity')
            xlabel(app.Plot1, 'Velocity (mph)')
            ylabel(app.Plot1, 'Engine Speed (rpm)')
            zlabel(app.Plot1, 'Z')
            app.Plot2 = uiaxes(app.GridLayout3);
            title(app.Plot2, 'Acceleration Distance')
            xlabel(app.Plot2, 'Time (s)')
            ylabel(app.Plot2, 'Distance (ft)')
            zlabel(app.Plot2, 'Z')
            app.Plot3 = uiaxes(app.GridLayout3);
            title(app.Plot3, 'Max Speed')
            xlabel(app.Plot3, 'Distance (ft)')
            ylabel(app.Plot3, 'Velocity (mph)')
            zlabel(app.Plot3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BajaCVTSim_App

            % Associate the Simulink Model
            app.Simulation = simulation('BajaCVTSim_SimulinkModel');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end